classdef SARPostProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        NumberofFFTSpacePaddedEditField  matlab.ui.control.NumericEditField
        NumberofFFTSpacePaddedEditFieldLabel  matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        NumberofFFTTimePaddedEditField  matlab.ui.control.NumericEditField
        NumberofFFTTimePaddedEditFieldLabel  matlab.ui.control.Label
        NumberofFramesEditField         matlab.ui.control.NumericEditField
        NumberofFramesEditFieldLabel    matlab.ui.control.Label
        BandwidthGHzEditField           matlab.ui.control.NumericEditField
        BandwidthGHzEditFieldLabel      matlab.ui.control.Label
        DyEditField                     matlab.ui.control.NumericEditField
        DyEditFieldLabel                matlab.ui.control.Label
        DxEditField                     matlab.ui.control.NumericEditField
        DxEditFieldLabel                matlab.ui.control.Label
        DirectoryEditField_3            matlab.ui.control.EditField
        DirectoryEditField_3Label       matlab.ui.control.Label
        DirectoryEditField_2            matlab.ui.control.EditField
        DirectoryEditField_2Label       matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        dyEditFieldLabel                matlab.ui.control.Label
        dxEditField                     matlab.ui.control.NumericEditField
        dyEditField                     matlab.ui.control.NumericEditField
        dxEditFieldLabel                matlab.ui.control.Label
        NumberofSamplesEditField        matlab.ui.control.NumericEditField
        NumberofSamplesEditFieldLabel   matlab.ui.control.Label
        NumberofChirpsEditField         matlab.ui.control.NumericEditField
        NumberofChirpsEditFieldLabel    matlab.ui.control.Label
        ChirpTimeusEditField            matlab.ui.control.NumericEditField
        ChirpTimeusEditFieldLabel       matlab.ui.control.Label
        RampEndTimeusEditField          matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel     matlab.ui.control.Label
        CentreFrequencyMHzEditField     matlab.ui.control.NumericEditField
        CentreFrequencyMHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyMssEditField   matlab.ui.control.NumericEditField
        SamplingFrequencyMssEditFieldLabel  matlab.ui.control.Label
        FrequencySlopeMHzusEditField    matlab.ui.control.NumericEditField
        FrequencySlopeMHzusEditFieldLabel  matlab.ui.control.Label
        GenerateRangeProfileButton      matlab.ui.control.Button
        Generate3DSARImageButton        matlab.ui.control.Button
        Generate2DSARImageButton        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Generate2DSARImageButton
        function Generate2DSARImageButtonPushed(app, event)
            try
                [xAxis, yAxis, sarImage] = sar1D(app.DirectoryEditField.Value, app.NumberofSamplesEditField.Value, app.NumberofFramesEditField.Value, ...
                    app.NumberofChirpsEditField.Value, app.SamplingFrequencyMssEditField.Value*10^6, app.CentreFrequencyMHzEditField.Value*10^9, ...
                    app.NumberofFFTTimePaddedEditField.Value);

                imagesc(app.UIAxes, xAxis, yAxis, sarImage);
                xlabel(app.UIAxes, 'Aperture Position (frame index)');
                ylabel(app.UIAxes, 'Range [m]');
                title(app.UIAxes, '1D SAR Strip Image');
                colormap(app.UIAxes, jet);
                colorbar(app.UIAxes);
                axis(app.UIAxes, 'xy');
            catch ME
                app.StatusTextArea.Value = getReport(ME);
            end
        end

        % Button pushed function: GenerateRangeProfileButton
        function GenerateRangeProfileButtonPushed(app, event)
            try
                [spectrum, range] = rangeProfile(app.DirectoryEditField_3.Value, app.NumberofSamplesEditField.Value, ...
                    app.NumberofChirpsEditField.Value, app.NumberofFramesEditField.Value, app.NumberofFFTTimePaddedEditField.Value, ...
                    (app.BandwidthGHzEditField.Value*10^9), (app.SamplingFrequencyMssEditField.Value*10^6), (app.ChirpTimeusEditField.Value*10^-6));
    
                plot(app.UIAxes, range, 20*log10(spectrum))
                grid(app.UIAxes, 'on'); 
                xlabel(app.UIAxes, 'Range (m)');
                ylabel(app.UIAxes, 'Magnitude (dB)');
                title(app.UIAxes, 'Range Profile');  
            catch ME
                app.StatusTextArea.Value = getReport(ME);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Generate2DSARImageButton = uibutton(app.UIFigure, 'push');
            app.Generate2DSARImageButton.ButtonPushedFcn = createCallbackFcn(app, @Generate2DSARImageButtonPushed, true);
            app.Generate2DSARImageButton.Text = 'Generate 2D SAR Image';
            app.Generate3DSARImageButton = uibutton(app.UIFigure, 'push');
            app.Generate3DSARImageButton.Text = 'Generate 3D SAR Image';
            app.GenerateRangeProfileButton = uibutton(app.UIFigure, 'push');
            app.GenerateRangeProfileButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRangeProfileButtonPushed, true);
            app.GenerateRangeProfileButton.Text = 'Generate Range Profile';
            app.FrequencySlopeMHzusEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencySlopeMHzusEditFieldLabel.Text = 'Frequency Slope (MHz/us)';
            app.FrequencySlopeMHzusEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencySlopeMHzusEditField.Value = 79.99;
            app.SamplingFrequencyMssEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyMssEditFieldLabel.Text = 'Sampling Frequency (Ms/s)';
            app.SamplingFrequencyMssEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyMssEditField.Value = 6;
            app.CentreFrequencyMHzEditFieldLabel = uilabel(app.UIFigure);
            app.CentreFrequencyMHzEditFieldLabel.Text = 'Centre Frequency (MHz)';
            app.CentreFrequencyMHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CentreFrequencyMHzEditField.Value = 79.186;
            app.RampEndTimeusEditFieldLabel = uilabel(app.UIFigure);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RampEndTimeusEditField.Value = 50;
            app.ChirpTimeusEditFieldLabel = uilabel(app.UIFigure);
            app.ChirpTimeusEditFieldLabel.Text = 'Chirp Time (us)';
            app.ChirpTimeusEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChirpTimeusEditField.Value = 42.667;
            app.NumberofChirpsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofChirpsEditFieldLabel.Text = 'Number of Chirps';
            app.NumberofChirpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofChirpsEditField.Value = 128;
            app.NumberofSamplesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSamplesEditFieldLabel.Text = 'Number of Samples';
            app.NumberofSamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSamplesEditField.Value = 256;
            app.dxEditFieldLabel = uilabel(app.UIFigure);
            app.dxEditFieldLabel.Text = 'dx';
            app.dyEditField = uieditfield(app.UIFigure, 'numeric');
            app.dyEditField.Value = 5;
            app.dxEditField = uieditfield(app.UIFigure, 'numeric');
            app.dxEditField.Value = 5;
            app.dyEditFieldLabel = uilabel(app.UIFigure);
            app.dyEditFieldLabel.Text = 'dy';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.DirectoryEditField_2Label = uilabel(app.UIFigure);
            app.DirectoryEditField_2Label.Text = 'Directory';
            app.DirectoryEditField_2 = uieditfield(app.UIFigure, 'text');
            app.DirectoryEditField_3Label = uilabel(app.UIFigure);
            app.DirectoryEditField_3Label.Text = 'Directory';
            app.DirectoryEditField_3 = uieditfield(app.UIFigure, 'text');
            app.DxEditFieldLabel = uilabel(app.UIFigure);
            app.DxEditFieldLabel.Text = 'Dx';
            app.DxEditField = uieditfield(app.UIFigure, 'numeric');
            app.DxEditField.Value = 200;
            app.DyEditFieldLabel = uilabel(app.UIFigure);
            app.DyEditFieldLabel.Text = 'Dy';
            app.DyEditField = uieditfield(app.UIFigure, 'numeric');
            app.DyEditField.Value = 200;
            app.BandwidthGHzEditFieldLabel = uilabel(app.UIFigure);
            app.BandwidthGHzEditFieldLabel.Text = 'Bandwidth (GHz)';
            app.BandwidthGHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandwidthGHzEditField.Value = 3.412907;
            app.NumberofFramesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFramesEditFieldLabel.Text = 'Number of Frames';
            app.NumberofFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofFramesEditField.Value = 8;
            app.NumberofFFTTimePaddedEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFFTTimePaddedEditFieldLabel.Text = 'Number of FFT Time Padded';
            app.NumberofFFTTimePaddedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofFFTTimePaddedEditField.Value = 256;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '__';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 200;
            app.NumberofFFTSpacePaddedEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFFTSpacePaddedEditFieldLabel.Text = 'Number of FFT Space Padded';
            app.NumberofFFTSpacePaddedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofFFTSpacePaddedEditField.Value = 256;
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SARPostProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end